classdef EDS_Grain_Analyzer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        RegionEditField     matlab.ui.control.EditField
        RegionLabel         matlab.ui.control.Label
        GrainLabel          matlab.ui.control.Label
        FileLocationLabel   matlab.ui.control.Label
        SelectGrainButton   matlab.ui.control.Button
        SheetNameEditField  matlab.ui.control.EditField
        SheetNameLabel      matlab.ui.control.Label
        WriteDataButton     matlab.ui.control.Button
        LoadDataButton      matlab.ui.control.Button
        UIAxes              matlab.ui.control.UIAxes
    end

    properties (Access = private)
        g = 0;                                      % counter for number of grains selected (resets on sheet name change)
        n = 0;                                      % counter for number of ginput selections (does not reset)
        
        start_path                                  % start location when uigetfile is called to load data
        write_path                                  % location the table is written to
        excel_filename = 'EDS Grain Analysis.xlsx'; % name of file being written

        map_size                                    % [Y Size, X Size]
        data                                        % three dimensional array of the original B, C and Si intensities

        x                                           % N x 2 dimensional array containing that start and end x position of the user grain selection
        y                                           % N x 2 dimensional array containing that start and end y position of the user grain selection

        m = [0.75, -0.45];                          % Scaling factors for C/B and Si/B
        b = [0.83, 0.63];                           % Scaling factors for C/B and Si/B
        atomic_mass = [10.811, 12.011, 28.0855];    % Atomic mass of B, C and Si
    end    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)
            % Turn off the figure window so the user can see the uigetfile window
            app.UIFigure.Visible = 'off';
            app.UIAxes.Visible = 'off';
            
            % Clear axes
            cla(app.UIAxes)

            % Restore default properties
            app.FileLocationLabel.Text = '';
        
            %% Prompt user to select 3 data files
            % If the program has just started, start in default folder and 
            % allow only CSV files to be selected
            if app.n == 0
                app.start_path = '*.csv';
            end       

            [filename, path] = uigetfile(app.start_path, 'Select EDS data files', 'MultiSelect', 'on');
            app.UIFigure.Visible = 'on';

            % Determine number of files selected
            number_of_files = length(filename);

            % Turn the figure window back on
            app.UIFigure.Visible = 'on';
        
            % Separate the path into its consituent folders
            folders_in_path = regexp(path,'[\\/]','split');
            num_folders = length(folders_in_path);
        
            % Determine delimiter type since it differs between Mac and Windows
            if contains(path,'/')
                delim = '/';
            elseif contains(path,'\')
                delim = '\';
            end
        
            % Remove the lowest level subfolder from the path
            folders_in_path(num_folders-1:num_folders) = '';

            if app.n == 0
                % The excel file will be written to the Parent folder of subfolder selected in uigetfile
                % and uigetfile will start in the parent folder the next time it is run
                app.write_path = append(strjoin(folders_in_path,delim),delim);
                app.start_path = append(app.write_path,'*.csv');
            else
                % The start_path is updated in case the user navigates to
                % a different directory from the one that was initially selected 
                app.start_path = append(strjoin(folders_in_path,delim),delim,'*.csv');
            end

            % Initialize selection counter and position arrays
            app.n = 1;
            app.x = [];
            app.y = [];
        
            %% Read Data
            app.map_size = size(readmatrix(strcat(path,filename{1})));
            app.data = zeros(app.map_size(1),app.map_size(2),number_of_files);
        
            for i = 1:number_of_files
                % Create path to file
                mapFile = strcat(path,filename{i});
        
                % Read the data file
                app.data(:,:,i) = readmatrix(mapFile);
            end

            % Remove tick labels
            app.UIAxes.XAxis.TickLabels = {};
            app.UIAxes.YAxis.TickLabels = {};

            % Scale axes to match the map size
            app.UIAxes.XLim = [1 app.map_size(2)];
            app.UIAxes.YLim = [1 app.map_size(1)];

            imshow(app.data, 'Parent', app.UIAxes)
            hold(app.UIAxes,'on')

            % Turn on axes
            app.UIAxes.Visible = 'on';
        end

        % Button pushed function: WriteDataButton
        function WriteDataButtonPushed(app, event)
            if app.g > 0
                % Reset the grain counter when the sheet name is changed
                app.g = 0;
    
                % Update the grain counter
                app.GrainLabel.Text = append('Grain: ', int2str(app.g));
                
                % Write the data to the sheetname specified in the sheetname edit field
                sheetname = app.SheetNameEditField.Value;
    
                % If the sheetname is empty, data is stored in 'Default'
                if isempty(sheetname)
                    sheetname = 'Default';
                % Check if sheetname exceeds Excel's 31 character limit and truncate it
                elseif size(sheetname,2)>31
                    sheetname = sheetname(1:31);
                end
                
                %% Compute average intensity for each grain
                [counts, intensity_ratio, scaling_factor, rel_intensity, wt_fraction, mol, at_percent] = deal(ones(app.n - 1,3));
            
                % Average the intensities contained within the user specified region and store it in an 
                % N x 3 array where the columns correspond to B, C and Si respectively
                for i = 1:app.n
                    counts(i,1) = mean(app.data(app.y(i,1):app.y(i,2), app.x(i,1):app.x(i,2), 1), 'all');
                    counts(i,2) = mean(app.data(app.y(i,1):app.y(i,2), app.x(i,1):app.x(i,2), 2), 'all');
                    counts(i,3) = mean(app.data(app.y(i,1):app.y(i,2), app.x(i,1):app.x(i,2), 3), 'all');
                end
        
                %% Apply the Scaling Factors
                for i = 2:3
                    % Compute elemental intensity ratios to be used in scaling factor calcs
                    intensity_ratio(:,i) = counts(:,i) ./ counts(:,1);
                    % Calculate scaling factors for each point along line averaged intensity
                    scaling_factor(:,i) = app.b(i-1) * exp(app.m(i-1) .* intensity_ratio(:,i));
                    % Apply scaling factors to get corrected relative intensity of each element
                    rel_intensity(:,i) = scaling_factor(:,i) .* intensity_ratio(:,i);
                end
                
                % Compute the total intensity
                total_intensity = rel_intensity(:,1) + rel_intensity(:,2) + rel_intensity(:,3);
            
                for i = 1:3
                    % Convert corrected intensities to weight fractions
                    wt_fraction(:,i) = rel_intensity(:,i) ./ total_intensity;
                    % Convert weight fractions to relative mol fraction
                    mol(:,i) = wt_fraction(:,i) / app.atomic_mass(i);
                end
            
                % Compute the total number of moles
                total_mols = mol(:,1) + mol(:,2) + mol(:,3);
            
                for i = 1:3
                    % Convert relative mol fraction to atomic percents
                    at_percent(:,i) = 100 * mol(:,i) ./ total_mols;
                end
            
                % Compute the B to C ratio
                b_to_c_ratio = at_percent(:,1) ./ at_percent(:,2);
            
                %% Prepare data for export
                % Combine the X, Y, Atomic Percent and B to C Ratio columns
                column_data = array2table(num2cell([app.x, app.y, at_percent, b_to_c_ratio]));
            
                % Label the data using the user input in the  region edit field
                region_label = {app.RegionEditField.Value, blanks(1), blanks(1), blanks(1), blanks(1), blanks(1), blanks(1), blanks(1)};
    
                % Create data headers to label the columns
                column_labels = {'X Start', 'X End', 'Y Start', 'Y End', 'B (at.%)', 'C (at.%)', 'Si (at.%)', 'B/C Ratio'}; 
    
                % Creat a row of blanks to improve readability of written data
                separator = {blanks(1), blanks(1), blanks(1), blanks(1), blanks(1), blanks(1), blanks(1), blanks(1)};
            
                % Combine data and headers into one big table to write to Excel
                composite_data = [region_label; column_labels; column_data; separator];
    
                % Define filename to write the data to
                full_excel_filename = fullfile(app.write_path, app.excel_filename);
            
                %% Write data to output Excel spreadsheet
                writetable(composite_data, full_excel_filename, 'Sheet', sheetname, 'WriteVariableNames', false, 'WriteMode', 'append');
            
                % Specify the location of the written data
                app.FileLocationLabel.Text = append('Sheet written to ', app.excel_filename, ' in ', app.write_path);
            end
        end

        % Button pushed function: SelectGrainButton
        function SelectGrainButtonPushed(app, event)
            % Record location of first point
            [x_coord(1,1), y_coord(1,1)] = ginput(1);

            % Plot the first point for user reference
            pt1 = scatter(x_coord(1,1), y_coord(1,1), 'w', 'filled', 'Parent', app.UIAxes);

            % Record location of second point
            [x_coord(1,2), y_coord(1,2)] = ginput(1);

            % Plot the second point for user reference
            pt2 = scatter(x_coord(1,2), y_coord(1,2), 'w', 'filled', 'Parent', app.UIAxes);
            
            % Determine corner of rectangle
            x_min = min(x_coord); x_max = max(x_coord);
            y_min = min(y_coord); y_max = max(y_coord);

            % Compute rectangle dimensions
            w = x_max - x_min;
            h = y_max - y_min;

            % Discard user selection if the either point lies outside of the map bounds
            if (x_min < 1) || (y_min < 1) || (x_max > app.map_size(2)) || (y_max > app.map_size(1))
                app.FileLocationLabel.Text = 'Grain selection area outside of axis bounds!';
                delete(pt1)
                delete(pt2)

            % Otherwise store the selection
            else
                app.FileLocationLabel.Text = '';

                % Plot rectangle on top of image
                rectangle('Position', [x_min y_min w h], 'EdgeColor', 'w', 'LineWidth', 2, 'Parent', app.UIAxes)
        
                % Store values
                % Start is top left and end is bottom right since the image origin is top left
                app.x(app.n,1) = ceil(x_min); app.x(app.n,2) = floor(x_max);
                app.y(app.n,1) = ceil(y_min); app.y(app.n,2) = floor(y_max);
                
                % Increment the counters
                app.n = app.n + 1;
                app.g = app.g + 1;
                app.GrainLabel.Text = append('Grain: ', int2str(app.g));
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.ZTick = [];
            app.UIAxes.ZTickLabel = '';
            app.UIAxes.Visible = 'off';
            app.LoadDataButton = uibutton(app.UIFigure, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Text = 'Load Data';
            app.WriteDataButton = uibutton(app.UIFigure, 'push');
            app.WriteDataButton.ButtonPushedFcn = createCallbackFcn(app, @WriteDataButtonPushed, true);
            app.WriteDataButton.Text = 'Write Data';
            app.SheetNameLabel = uilabel(app.UIFigure);
            app.SheetNameLabel.Text = 'Sheet Name:';
            app.SheetNameEditField = uieditfield(app.UIFigure, 'text');
            app.SelectGrainButton = uibutton(app.UIFigure, 'push');
            app.SelectGrainButton.ButtonPushedFcn = createCallbackFcn(app, @SelectGrainButtonPushed, true);
            app.SelectGrainButton.Text = 'Select Grain';
            app.FileLocationLabel = uilabel(app.UIFigure);
            app.FileLocationLabel.Text = 'File Location';
            app.GrainLabel = uilabel(app.UIFigure);
            app.GrainLabel.Text = 'Grain: 0';
            app.RegionLabel = uilabel(app.UIFigure);
            app.RegionLabel.Text = 'Region:';
            app.RegionEditField = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EDS_Grain_Analyzer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end